classdef App_HI5_position_track < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Image                   matlab.ui.control.Image
        AngledegEditFieldLabel  matlab.ui.control.Label
        AngledegEditField       matlab.ui.control.NumericEditField
        descriptionLabel        matlab.ui.control.Label
        TaskLabel               matlab.ui.control.Label
        instructionLabel        matlab.ui.control.Label
        blue                    matlab.ui.control.Image
        counter                 matlab.ui.control.Label
        trialCounter            matlab.ui.control.Label
        PauseButton             matlab.ui.control.StateButton
        StartPositionButton     matlab.ui.control.Button
        speed                   matlab.ui.control.Label
        TrainingButton          matlab.ui.control.Button
        logic                   matlab.ui.control.Button
        logic_2                 matlab.ui.control.Button
        enter                   matlab.ui.control.Image
        rightArrow              matlab.ui.control.Image
        tKey                    matlab.ui.control.Image
        space                   matlab.ui.control.Image
        Lamp                    matlab.ui.control.Lamp
        logic_3Button           matlab.ui.control.StateButton
        ErrorTextAreaLabel      matlab.ui.control.Label
        ErrorTextArea           matlab.ui.control.TextArea
        StartTrainingButton     matlab.ui.control.Button
        red                     matlab.ui.control.Image
        UIAxes                  matlab.ui.control.UIAxes
    end


    methods (Access = public)
        
function checkPauseStopStatus(app,flag)
        clear u_pause
        u_pause = udpport('LocalPort', 2222);
        if app.PauseButton.Value
            app.counter.Visible=true;
            app.counter.Text='Paused'; 
            write(u_pause, strcat(num2str(app.PauseButton.Value)), 'string','LocalHost', 1111);
            waitfor(app.PauseButton,'Value',false)
            write(u_pause, strcat(num2str(app.PauseButton.Value)), 'string','LocalHost', 1111);
        end
    end
end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: PauseButton
        function PauseButtonValueChanged(app, event)
        if app.PauseButton.Value
            app.PauseButton.Text = 'Resume (Press Space)';
        else
            app.PauseButton.Text = 'Pause (Press Space)';
        end
        end

        % Button pushed function: StartPositionButton
        function StartPositionButtonPushed(app, event)
        %this code foverns the 'Match the position' task
        app.enter.Visible=false;
        app.space.Visible=true;
        app.tKey.Visible=false;
        app.trialCounter.Visible=true;
        app.StartTrainingButton.Visible=false;
        app.TaskLabel.Visible=false;
        app.descriptionLabel.Visible=false;
        app.PauseButton.Visible=true;
        app.speed.Visible=true;
        app.ErrorTextAreaLabel.Visible=false;
        app.ErrorTextArea.Visible = false;
        app.logic.Enable = false;
        app.logic_2.Enable = false;
        app.logic_3Button.Enable = false;
        
        %init udp
        clear u1 %reading in App from script
        clear u2 %writing in script
        clear u3 % writing in app
        clear u4 %reading in script from app
        u1 = udpport("LocalPort",3000); % open udp for FES pw from simulink, clear port if error
        u3 = udpport('LocalPort', 2000);% udp for writing in app to send data to script
        
        %variables
        experiment = 4;
        trial_length = 3;
        countDown = 5;%length of 3,2,1
        numTrials=80;%must be divisible by 8. 8 trials cover going to each position (4) once and going back to zero
        numblocks=2;
        
        %circle calcs
        r=850;
        x=640;
        y=0;
        th = 0:pi/800:pi;
        xunit = r * cos(th) + x;
        yunit = r * sin(th) + y;
        xunit=xunit(201:601);
        yunit=yunit(201:601);
        plot(app.UIAxes,xunit,yunit, '--','color','white',"LineWidth",2);
        set(app.UIAxes,'Color',[0.5 0.5 0.5])
        set(app.UIAxes,'visible','off')
        app.UIFigure.Position = [0 0 1280 960];
        framePos=app.UIFigure.Position;
        app.blue.Position=[framePos(1)/2,framePos(2)/2,40,40];
        app.Lamp.Position=[framePos(1)/2,framePos(2)/2,40,40];
        app.UIAxes.Position = [10 10 1280 640];
        trial_index = 1;
        trial_pre = 1;
        target_pos_prev = 40;
        block=1;
        data_box = [];
        
        write(u3, strcat(uniform_data_trials(experiment),uniform_data_trials(numTrials),uniform_data_trials(trial_length),uniform_data_trials(numblocks)), 'string','LocalHost', 1000);
        flush(u1);
        
        tic
        
        while block <= numblocks
            flush(u1)
            data_int_array = int8(read(u1,20,"int8"));  % ensure buffer is multiple of number of electrodes used 
            for i = 1:4:17
                single_data_sample = typecast(data_int_array(i:i+3),'single');
                data_box = [data_box single_data_sample];
            end
%             target_pos = data_box(1:10);
%             subject_traj = data_box(11:20);
%             Elapsed_Time = data_box(22:31);
            speedVal=data_box(5);
            data_box=[];

            
            trial_in_epoch=1;
            app.counter.Text='';
    
            %start of the trial marker 
            
            while (trial_index <= numTrials*numblocks)
                
                if speedVal ==0
                    speedFlag = 'Slow';
                    app.speed.FontColor=[0 1 0];
                elseif speedVal==1
                    speedFlag = 'Fast';
                    app.speed.FontColor=[1 0 0];
                end
                app.speed.Text=speedFlag;
                
                if mod(trial_index, numTrials) == 1
                    
                    if ~isempty(app)%pause button functionality
                        checkPauseStopStatus(app)
                    end
                    
                    c = clock;
                    clockCurrent = c(4)*3600+c(5)*60+c(6);
                    clockStart = clockCurrent;
                    app.blue.Position=[615,455,50,50];
                    app.Lamp.Position=[615,455,50,50];
                    app.Lamp.Color=[1,1,1];

                    while (clockCurrent < clockStart + countDown)
                        flush(u1)
                        c = clock;
                        clockCurrent = c(4)*3600+c(5)*60+c(6);
                        
                        if(clockCurrent > clockStart + 4)
                            app.counter.Text='1';
                        elseif (clockCurrent > clockStart + 3)
                            app.counter.Text='2';
                        elseif (clockCurrent > clockStart + 2)
                            app.counter.Text='3';
                        elseif (clockCurrent > clockStart)
                            app.counter.Text='Ready..';
                        end
                        
                        data_int_array = int8(read(u1,20,"int8"));  % ensure buffer is multiple of number of electrodes used 
                        for i = 1:4:17
                            single_data_sample = typecast(data_int_array(i:i+3),'single');
                            data_box = [data_box single_data_sample];
                        end
                        subject_traj = data_box(2);
                        data_box=[];
                        

%                         while k <= 10
%                             
%                             subject_traj_sample = floor(subject_traj(k)) + floor( (subject_traj(k)-floor(subject_traj(k)))/0.25) * 0.25;
%                             
%                             if subject_traj_sample>50
%                                 subject_traj_sample=50;
%                             elseif subject_traj_sample<-50
%                                 subject_traj_sample=-50;
%                             end
%                             app.TaskLabel.Visible=true;
% %                             app.TaskLabel.Text=num2str(subject_traj_sample);
%                             redX=xunit(4*(50.25-subject_traj_sample))-20;
%                             redY=yunit(4*(50.25-subject_traj_sample))-400;
%                             app.red.Position=[redX+10,redY+10,30,30];
%                             
%                             k = k+1;
%                         end
                        
%                         subject_traj_sample = floor(subject_traj(k)) + floor( (subject_traj(k)-floor(subject_traj(k)))/0.25) * 0.25;
                        subject_traj_sample = floor(subject_traj) + floor( (subject_traj-floor(subject_traj))/0.25) * 0.25;
                           
                        if subject_traj_sample>50
                            subject_traj_sample=50;
                        elseif subject_traj_sample<-50
                            subject_traj_sample=-50;
                        end
                        app.TaskLabel.Visible=true;
%                             app.TaskLabel.Text=num2str(subject_traj_sample);
                        redX=xunit(4*(50.25-subject_traj_sample))-20;
                        redY=yunit(4*(50.25-subject_traj_sample))-400;
                        app.red.Position=[redX+10,redY+10,30,30];
                        pause(0.001)
                    end
                end
                
                app.trialCounter.Text=num2str(trial_index);
                app.counter.Text='';
                app.speed.Text=speedFlag;
                
                %%initiate stuff
                c = clock;
                clock_Current = c(4)*3600+c(5)*60+c(6);
                clock_Start = clock_Current;
                Elapsed_Time = 0;
                data_box = [];
                while (Elapsed_Time < trial_length + 1)
                    
                    c = clock;
                    clock_Current = c(4)*3600+c(5)*60+c(6);
                    Elapsed_Time = clock_Current - clock_Start;
                    
                    if (trial_index == numTrials*numblocks) && (Elapsed_Time > trial_length - 0.2)
                        trial_index = trial_index + 1;
                        break
                    end
                    
                    data_int_array = int8(read(u1,20,"int8"));  % ensure buffer is multiple of number of electrodes used 
                    for i = 1:4:17
                        single_data_sample = typecast(data_int_array(i:i+3),'single');
                        data_box = [data_box single_data_sample];
                    end
                    target_pos = data_box(1);
                    subject_traj = data_box(2);
%                     Elapsed_Time = data_box(22:31);
                    trial_num = data_box(4);
                    speedVal=data_box(5);
                    data_box=[];


                    trial_index = trial_num;
                    if trial_pre ~= trial_index
                        flush(u1);
                        trial_pre = trial_index;
                        break
                    end
                    
                    
%                     target_pos_sample = floor(target_pos) + floor( (target_pos-floor(target_pos))/0.25) * 0.25;
                    blueX = xunit(4*(50.25+target_pos))-20;
                    blueY= yunit(4*(50.25+target_pos))-400;
                    app.blue.Position=[blueX,blueY,50,50];
                    app.Lamp.Position=[blueX,blueY,50,50];
                    

                    if speedVal == 0
%                         Elapsed_Time=Elapsed_Time*40/abs(target_pos);
                        if target_pos ~= 0
                            val=1-(Elapsed_Time*40)/abs(target_pos)/3;
                        else
                            val=1-(Elapsed_Time*40)/abs(target_pos_prev)/3;
                        end
                        
                        if val < 0
                            val=0;
                        end
                        if val >1
                            val=1;
                        end
                        
%                         app.TaskLabel.Text = num2str(val);
%                         app.TaskLabel.Visible = true;
                        
                        app.Lamp.Color=[val,val,1];

                    elseif speedVal == 1
%                         Elapsed_Time=;
                        if target_pos ~= 0
                            val=1-(Elapsed_Time*40)/abs(target_pos)/2;
                        else
                            val=1-(Elapsed_Time*40)/abs(target_pos_prev)/2;
                        end
%                         val=val*40/abs(target_pos_sample);
                        if val < 0
                            val=0;
                        end
                        if val >1
                            val=1;
                        end
                        app.Lamp.Color=[val,val,1];
                    end
                    
                    subject_traj_sample = floor(subject_traj) + floor( (subject_traj-floor(subject_traj))/0.25) * 0.25;
                    
                    if subject_traj_sample>50
                        subject_traj_sample=50;
                    elseif subject_traj_sample<-50
                        subject_traj_sample=-50;
                    end
%                     app.TaskLabel.Visible=true;
%                         app.TaskLabel.Text=num2str(subject_traj_sample);
                    redX=xunit(4*(50.25-subject_traj_sample))-20;
                    redY=yunit(4*(50.25-subject_traj_sample))-400;
                    app.red.Position=[redX+10,redY+10,30,30];
                    
                   
                    if target_pos ~= 0
                        target_pos_prev=target_pos;
                    end
                end
               
%                 app.Lamp.Color=[1,1,1];
                trial_in_epoch = trial_in_epoch+1;
%                 trial_index = trial_index + 1;
            end
    
            block=block+1;
            app.TaskLabel.Visible=true;
            app.TaskLabel.Text='To continue press ';
            app.TaskLabel.Visible=false;
        end
        app.trialCounter.Visible=false;
        app.PauseButton.Visible=false;
        app.space.Visible=false;
        app.TaskLabel.Visible=false;
        app.descriptionLabel.Visible=false;
        app.instructionLabel.Visible = true;
        app.instructionLabel.Text='This is the end of the experiment. Thank you!';
        app.speed.Visible=false;
        app.StartPositionButton.Visible=false;
        
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            switch key
                case 'return'
                    if app.logic.Enable
                        StartPositionButtonPushed(app, event);
                    end
                case 'space'
                    if app.PauseButton.Value == false
                        app.PauseButton.Value = true;
                    elseif app.PauseButton.Value == true
                        app.PauseButton.Value = false;
                    end
                case 't'
                    if app.logic_3Button.Enable
                        StartTrainingButtonPushed(app, event);
                    end
            end

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
%             grip = load('grip.mat');
%             gripTarget = load('gripTarget.mat');
%             torque = load('Torque.mat');
%             target_fullAssisted = load('hi5Target_fullAssisted.mat');
%             target_semiAssisted = load('hi5Target_SemiAssisted.mat');
%             target_zeroAssisted = load('hi5Target_ZeroAssisted.mat');
%             positioning = load('hi5Position.mat');
%             Username = char(load('username.mat').Username);
%             data={};
%             gripname = strcat(Username,'_',char(datetime('now','Format','yyyy_MM_dd''T''HHmm')),'_grip');
%             torquename = strcat(Username,'_',char(datetime('now','Format','yyyy_MM_dd''T''HHmm')),'_torque');
%             target_fullAssistedname = strcat(Username,'_',char(datetime('now','Format','yyyy_MM_dd''T''HHmm')),'_hi5Target_fullAssisted');
%             target_semiAssistedname = strcat(Username,'_',char(datetime('now','Format','yyyy_MM_dd''T''HHmm')),'_hi5Target_semiAssisted');
%             target_zeroAssistedname = strcat(Username,'_',char(datetime('now','Format','yyyy_MM_dd''T''HHmm')),'_hi5Target_zeroAssisted');
%             positionname = strcat(Username,'_',char(datetime('now','Format','yyyy_MM_dd''T''HHmm')),'_hi5Position');
%             gripTargetname = strcat(Username,'_',char(datetime('now','Format','yyyy_MM_dd''T''HHmm')),'_gripTarget');
%             
%             data.(gripname) = grip;
%             data.(gripTargetname) = gripTarget;
%             data.(torquename)=torque;
%             data.(target_fullAssistedname)=target_fullAssisted;
%             data.(target_semiAssistedname)=target_semiAssisted;
%             data.(target_zeroAssistedname)=target_zeroAssisted;
%             data.(positionname)=positioning;
%             
%             dataname = strcat(Username,'_',char(datetime('now','Format','yyyy_MM_dd''T''HHmm')));
%             
%             
%             save(dataname,'data')
            
            delete(app)
            
        end

        % Button pushed function: StartTrainingButton
        function StartTrainingButtonPushed(app, event)
        %this code foverns the 'Match the position' task
        app.enter.Visible=false;
        app.space.Visible=true;
        app.tKey.Visible=false;
        app.trialCounter.Visible=true;
        app.StartTrainingButton.Visible=false;
        app.TaskLabel.Visible=false;
        app.descriptionLabel.Visible=false;
        app.PauseButton.Visible=true;
        app.speed.Visible=true;
        app.ErrorTextAreaLabel.Visible=false;
        app.ErrorTextArea.Visible = false;
        app.logic.Enable = false;
        app.logic_2.Enable = false;
        app.logic_3Button.Enable = false;
        
        %init udp
        clear u1 %reading in App from script
        clear u2 %writing in script
        clear u3 % writing in app
        clear u4 %reading in script from app
        u1 = udpport("LocalPort",3000); % open udp for FES pw from simulink, clear port if error
        u3 = udpport('LocalPort', 2000);% udp for writing in app to send data to script
        
        %variables
        experiment = 8;
        trial_length = 3;
        countDown = 5;%length of 3,2,1
        numTrials=8;%must be divisible by 8. 8 trials cover going to each position (4) once and going back to zero
        numblocks=2;
      
        %circle calcs
        r=850;
        x=640;
        y=0;
        th = 0:pi/800:pi;
        xunit = r * cos(th) + x;
        yunit = r * sin(th) + y;
        xunit=xunit(201:601);
        yunit=yunit(201:601);
        plot(app.UIAxes,xunit,yunit, '--','color','white');
        app.UIFigure.Position = [0 0 1280 960];
        framePos=app.UIFigure.Position;
        app.blue.Position=[framePos(1)/2,framePos(2)/2,40,40];
        app.UIAxes.Position = [10 10 1280 640];
        trial_index = 1;
        
        block=1;
        data_box = [];
        
        
        write(u3, strcat(uniform_data_trials(experiment),uniform_data_trials(numTrials),uniform_data_trials(trial_length),uniform_data_trials(numblocks)), 'string','LocalHost', 1000);
        
        flush(u1);
        
        while block <= numblocks

            if block ==1
                speedFlag = 'Slow';
                app.speed.FontColor=[0 1 0];
            elseif block==2
                speedFlag = 'Fast';
                app.speed.FontColor=[1 0 0];
            end
            app.speed.Text=speedFlag;
            
            
            trial_in_epoch=1;
            app.counter.Text='';
    
            %start of the trial marker 
            
            while (trial_in_epoch <=numTrials)
                
                
                if mod(trial_index, numTrials) == 1
                    c = clock;
                    clockCurrent = c(4)*3600+c(5)*60+c(6);
                    clockStart = clockCurrent;
                    app.blue.Position=[615,455,50,50];
                    app.Lamp.Position=[615,455,50,50];
                    
                    while (clockCurrent < clockStart + countDown)
                        c = clock;
                        clockCurrent = c(4)*3600+c(5)*60+c(6);
                        
                        if(clockCurrent > clockStart + 4)
                            app.counter.Text='1';
                        elseif (clockCurrent > clockStart + 3)
                            app.counter.Text='2';
                        elseif (clockCurrent > clockStart + 2)
                            app.counter.Text='3';
                        elseif (clockCurrent > clockStart)
                            app.counter.Text='Ready..';
                        end
                        
                        data_int_array = int8(read(u1,16,"int8"));  % ensure buffer is multiple of number of electrodes used 
                        for i = 1:4:13
                            single_data_sample = typecast(data_int_array(i:i+3),'single');
                            data_box = [data_box single_data_sample];
                        end
                        target_traj = data_box(1);
                        subject_traj = data_box(2);
                        data_box=[];
                        
%                         k = 1;
%                         while k <= 10
                            
                            target_traj_sample = floor(target_traj) + floor( (target_traj-floor(target_traj))/0.25) * 0.25;
                            target_traj_sample=-target_traj_sample;
                            blueX = xunit(4*(50.25+target_traj_sample))-20;
                            blueY= yunit(4*(50.25+target_traj_sample))-400;
                            app.Lamp.Position=[blueX,blueY,50,50];
                            
                            subject_traj_sample = floor(subject_traj) + floor( (subject_traj-floor(subject_traj))/0.25) * 0.25;
                            redX=xunit(4*(50.25-subject_traj_sample))-20;
                            redY=yunit(4*(50.25-subject_traj_sample))-400;
                            app.red.Position=[redX+10,redY+10,30,30];
                            
%                             k = k+1;
%                         end
                    end
                end
                
                app.trialCounter.Text=['Trial # ' num2str(trial_index)];
                app.counter.Text='';
                app.speed.Text=speedFlag;
                
                %%initiate stuff
                c = clock;
                clock_Current = c(4)*3600+c(5)*60+c(6);
                clock_Start = clock_Current;
                Elapsed_Time = 0;
                data_box = [];
                while (Elapsed_Time < trial_length)
                    
                    c = clock;
                    clock_Current = c(4)*3600+c(5)*60+c(6);
                    Elapsed_Time = clock_Current - clock_Start;
%                     
%                     if Elapsed_Time + 0.1 >= trial_length % -0.02 for delay compensation
%                         break
%                     end
                    
                    data_int_array = int8(read(u1,16,"int8"));  % ensure buffer is multiple of number of electrodes used 
                    for i = 1:4:13
                        single_data_sample = typecast(data_int_array(i:i+3),'single');
                        data_box = [data_box single_data_sample];
                    end
                    target_pos = data_box(1);
                    subject_traj = data_box(2);
%                     Elapsed_Time = data_box(4);
                    data_box=[];
                    
                    target_pos_sample = floor(target_pos(1)) + floor( (target_pos(1)-floor(target_pos(1)))/0.25) * 0.25;
                    blueX = xunit(4*(50.25-target_pos_sample))-20;
                    blueY= yunit(4*(50.25-target_pos_sample))-400;
                    app.blue.Position=[blueX,blueY,50,50];
                    app.Lamp.Position=[blueX,blueY,50,50];
%                     
%                     k = 1;
%                     while k <= 10
                        
                        subject_traj_sample = floor(subject_traj) + floor( (subject_traj-floor(subject_traj))/0.25) * 0.25;
                        redX=xunit(4*(50.25-subject_traj_sample))-20;
                        redY=yunit(4*(50.25-subject_traj_sample))-400;
                        app.red.Position=[redX+10,redY+10,30,30];
                        
                        target_pos_sample = floor(target_pos) + floor( (target_pos-floor(target_pos))/0.25) * 0.25;
                        target_pos_sample=-target_pos_sample;
                        blueX = xunit(4*(50.25+target_pos_sample))-20;
                        blueY= yunit(4*(50.25+target_pos_sample))-400;
                        app.Lamp.Position=[blueX,blueY,50,50];
                        
%                         k = k+1;
%                         %pause(0.001)
%                     end
                    
                    if Elapsed_Time(1) >= trial_length
                    break
                    end
        
                end

                trial_in_epoch = trial_in_epoch+1;
                trial_index = trial_index + 1;
            end
    
            block=block+1;
            app.TaskLabel.Visible=true;
            app.TaskLabel.Text='To continue press ';
            app.TaskLabel.Visible=false;
        end
        app.trialCounter.Visible=false;
        app.PauseButton.Visible=false;
        app.space.Visible=false;
        app.TaskLabel.Visible=false;
        app.descriptionLabel.Visible=false;
        app.instructionLabel.Visible = true;
        app.instructionLabel.Text='This is the end of the experiment. Thank you!';
        app.speed.Visible=false;
        app.StartPositionButton.Visible=false;
        app.StartTrainingButton.Visible=false;
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.Visible = 'off';
            app.Image.ImageSource = 'background line_e.png';
            app.AngledegEditFieldLabel = uilabel(app.UIFigure);
            app.AngledegEditFieldLabel.Visible = 'off';
            app.AngledegEditFieldLabel.Text = 'Angle[deg]';
            app.AngledegEditField = uieditfield(app.UIFigure, 'numeric');
            app.AngledegEditField.Visible = 'off';
            app.descriptionLabel = uilabel(app.UIFigure);
            app.descriptionLabel.Text = 'Set your wrist to neutral position and press ';
            app.TaskLabel = uilabel(app.UIFigure);
            app.TaskLabel.Text = 'Follow the target';
            app.instructionLabel = uilabel(app.UIFigure);
            app.instructionLabel.Text = 'Try to follow the target position wihout tensing the forearm muscles too much';
            app.blue = uiimage(app.UIFigure);
            app.blue.Visible = 'off';
            app.blue.ImageSource = 'blue dot.png';
            app.counter = uilabel(app.UIFigure);
            app.counter.Text = '';
            app.trialCounter = uilabel(app.UIFigure);
            app.trialCounter.Text = '';
            app.PauseButton = uibutton(app.UIFigure, 'state');
            app.PauseButton.ValueChangedFcn = createCallbackFcn(app, @PauseButtonValueChanged, true);
            app.PauseButton.Visible = 'off';
            app.PauseButton.Text = 'Pause';
            app.StartPositionButton = uibutton(app.UIFigure, 'push');
            app.StartPositionButton.ButtonPushedFcn = createCallbackFcn(app, @StartPositionButtonPushed, true);
            app.StartPositionButton.Text = 'Start';
            app.speed = uilabel(app.UIFigure);
            app.speed.Text = '';
            app.TrainingButton = uibutton(app.UIFigure, 'push');
            app.TrainingButton.Visible = 'off';
            app.TrainingButton.Text = 'Training';
            app.logic = uibutton(app.UIFigure, 'push');
            app.logic.Enable = 'off';
            app.logic.Visible = 'off';
            app.logic_2 = uibutton(app.UIFigure, 'push');
            app.logic_2.Enable = 'off';
            app.logic_2.Visible = 'off';
            app.enter = uiimage(app.UIFigure);
            app.enter.ImageSource = 'enter-key.png';
            app.rightArrow = uiimage(app.UIFigure);
            app.rightArrow.Visible = 'off';
            app.rightArrow.ImageSource = 'right arrow.png';
            app.tKey = uiimage(app.UIFigure);
            app.tKey.Visible = 'off';
            app.tKey.ImageSource = 'T key.png';
            app.space = uiimage(app.UIFigure);
            app.space.Visible = 'off';
            app.space.ImageSource = 'spacebar-icon-25.jpg';
            app.Lamp = uilamp(app.UIFigure);
            app.logic_3Button = uibutton(app.UIFigure, 'state');
            app.logic_3Button.Enable = 'off';
            app.logic_3Button.Visible = 'off';
            app.logic_3Button.Text = 'logic_3';
            app.ErrorTextAreaLabel = uilabel(app.UIFigure);
            app.ErrorTextAreaLabel.Visible = 'off';
            app.ErrorTextAreaLabel.Text = 'Error:';
            app.ErrorTextArea = uitextarea(app.UIFigure);
            app.ErrorTextArea.Visible = 'off';
            app.StartTrainingButton = uibutton(app.UIFigure, 'push');
            app.StartTrainingButton.ButtonPushedFcn = createCallbackFcn(app, @StartTrainingButtonPushed, true);
            app.StartTrainingButton.Text = 'Training';
            app.red = uiimage(app.UIFigure);
            app.red.ImageSource = 'red_dot.png';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_HI5_position_track

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end