classdef App_grip_receiver < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        descriptionLabel  matlab.ui.control.Label
        TaskLabel         matlab.ui.control.Label
        instructionLabel  matlab.ui.control.Label
        StartButton       matlab.ui.control.Button
        blue              matlab.ui.control.Image
        trialCounter      matlab.ui.control.Label
        counter           matlab.ui.control.Label
        darkRed           matlab.ui.control.Image
        green             matlab.ui.control.Image
        yellow            matlab.ui.control.Image
        red               matlab.ui.control.Image
        NextButton        matlab.ui.control.Button
        logic             matlab.ui.control.Button
        PauseButton       matlab.ui.control.StateButton
        enter             matlab.ui.control.Image
        rightArrow        matlab.ui.control.Image
        upArrow           matlab.ui.control.Image
        space             matlab.ui.control.Image
        logic_2Button     matlab.ui.control.StateButton
        UIAxes            matlab.ui.control.UIAxes
    end

    methods (Access = public)
        
function checkPauseStopStatus(app,flag)
        if app.PauseButton.Value
            app.counter.Visible=true;
            app.counter.Text='Paused'; 
            waitfor(app.PauseButton,'Value',false)
            app.counter.Visible=true;
            app.counter.Text='Ready..'; 
            pause(2);
            app.counter.Text='3';
            pause(1);
            app.counter.Text='2';
            pause(1);
            app.counter.Text='1';
            pause(1);
            app.counter.Visible=false;
            app.counter.Text=' '; 
        end
    end
end
 

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
                         %start with hiding the unnecessary text
        app.enter.Visible=false;
        app.space.Visible=true;
        app.trialCounter.Visible=true;
        app.TaskLabel.Visible=false;
        app.descriptionLabel.Visible=false;
        app.StartButton.Visible=false;
        app.PauseButton.Visible=true;
        app.logic_2Button.Enable=false;

        % init
        clear u1 %reading in App from script
        clear u2 %writing in script
        clear u3 % writing in app
        clear u4 %reading in script from app
        u1 = udpport("LocalPort",4000); % open udp for FES pw from simulink, clear port if error
        u3 = udpport('LocalPort', 2000);% udp for writing in app to send data to script
        
        %variables
        experiment = 7;
        countdown = 5;
        numTrials=2;
        numEpochs=3;
        trial_length = 5;% grip for 3 second, rest for 2 second
        grip_length = 3;% grip for 3 second
      
        %circle calcs
%         r=850;
%         x=640;
%         y=0;
%         th = 0:pi/800:pi;
%         xunit = r * cos(th) + x;
%         yunit = r * sin(th) + y;
%         xunit=xunit(201:601);
%         yunit=yunit(201:601);
        plot(app.UIAxes,1,1)
        xline(app.UIAxes,0, '--','color','blue')
%         framePos=app.UIFigure.Position;
        app.UIAxes.Position = [493 100 1280 500];
        
        trials = 1;
        epoch=1;
        
        while (epoch <=numEpochs)
            c = clock;
            clockCurrent = c(4)*3600+c(5)*60+c(6);
            clockStart = clockCurrent;
            
            while (clockCurrent < clockStart + countdown)
                c = clock;
                clockCurrent = c(4)*3600+c(5)*60+c(6);
                if(clockCurrent > clockStart + 4)
                    app.counter.Text='1';
                elseif (clockCurrent > clockStart + 3)
                    app.counter.Text='2';
                elseif (clockCurrent > clockStart + 2)
                    app.counter.Text='3';
                elseif (clockCurrent > clockStart)
                    app.counter.Text='Ready..';
                end
                
                pause(0.001)%for some reason this pause is needed for smootheness
            end


            write(u3, strcat(num2str(experiment),uniform_data_trials(numTrials),uniform_data_trials(trial_length)), 'string','LocalHost', 1000);

            %initiate stuff before the trial loop (clock is the same as in script - no transmision)
            clockCurrent = 0;
            
            app.counter.Text='';
    
            trial=1;
            while(trial<=numTrials)
                app.trialCounter.Text=['Trial # ' num2str(trials)];
                
                if ~isempty(app)%pause button functionality
                checkPauseStopStatus(app)
                end
                
                while (clockCurrent < trial_length - 0.02)
                    flush(u1);
                    script_data = read(u1,32,"string");  % ensure buffer is multiple of number of electrodes used 
                    script_data = split(script_data,'');
                    strength = str2num(cell2mat(strcat(script_data(2),script_data(3),...
                        script_data(4),script_data(5),script_data(6),script_data(7),script_data(8),script_data(9)))); %force target
                    f = str2num(cell2mat(strcat(script_data(10),script_data(11),script_data(12),...
                        script_data(13),script_data(14),script_data(15),script_data(16),...
                        script_data(17)))); %force of the human
                    Error = str2num(cell2mat(strcat(script_data(18),script_data(19),script_data(20),...
                        script_data(21),script_data(22),script_data(23),script_data(24),...
                        script_data(25)))); %error between target force and human force
                    Elapsed_Time = str2num(cell2mat(strcat(script_data(26),script_data(27),script_data(28),...
                        script_data(29),script_data(30),script_data(31),script_data(32),...
                        script_data(33)))); %error between target force and human force
                    if Elapsed_Time >= trial_length
                        break
                    end
                    
                    clockCurrent = Elapsed_Time;
                    posError = -60*f;
                    angDeviation = posError/6400*90;
                    
                    switch strength
                        case 1
                             if (clockCurrent > grip_length)
                                app.blue.Visible=true;
                                app.green.Visible=false;
                                app.yellow.Visible=false;
                                app.darkRed.Visible=false;
                            elseif (clockCurrent > 0)
                                app.blue.Visible=false;
                                app.green.Visible=true;
                                app.yellow.Visible=false;
                                app.darkRed.Visible=false;       
                            end
                        case 2
                            if (clockCurrent > grip_length)
                                app.blue.Visible=true;
                                app.green.Visible=false;
                                app.yellow.Visible=false;
                                app.darkRed.Visible=false;
                            elseif (clockCurrent > 0)
                                app.blue.Visible=false;
                                app.green.Visible=false;
                                app.yellow.Visible=true;
                                app.darkRed.Visible=false;       
                            end
                        case 3
                            if (clockCurrent > grip_length)
                                app.blue.Visible=true;
                                app.green.Visible=false;
                                app.yellow.Visible=false;
                                app.darkRed.Visible=false;
                            elseif (clockCurrent > 0)
                                app.blue.Visible=false;
                                app.green.Visible=false;
                                app.yellow.Visible=false;
                                app.darkRed.Visible=true;       
                            end
                    end
                    
                    redY=-10*angDeviation+100;
                    app.red.Position=[625,redY,30,30];
                    
                    pause(0.001)
        
                end
                trial=trial+1;
                trials = trials + 1;
            end
            epoch = epoch+1;
            app.TaskLabel.Visible=true;
            app.upArrow.Visible=true;
            app.TaskLabel.Text='To continue press ';
%             waitfor(app.logic,'Enable','on')
            app.logic.Enable=false;
            app.TaskLabel.Visible=false;
            app.upArrow.Visible=false;
        
        end
        
        app.trialCounter.Visible=false;
        app.StartButton.Visible=false;
        app.PauseButton.Visible=false;
        app.space.Visible=false;
        app.TaskLabel.Visible=false;
        app.descriptionLabel.Visible=false;
        app.NextButton.Visible=true;
        app.instructionLabel.Text='Congratulations, you have finished this part of the experiment! To continue, press ';
        app.logic_2Button.Enable = true;
        app.rightArrow.Visible=true;
        app.UIAxes.Visible=false;
        app.red.Visible=false;
        app.blue.Visible=false;
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            App_grip_tracking;
            app.delete;
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            
            switch key
                case 'return'
                    StartButtonPushed(app, event);
                case 'rightarrow'
                    if app.logic_2Button.Enable
                        NextButtonPushed(app, event);
                    end
                case 'space'
                    if app.PauseButton.Value == false
                        app.PauseButton.Value = true;
                    elseif app.PauseButton.Value == true
                        app.PauseButton.Value = false;
                    end

                case 'uparrow'
                    app.logic.Enable=true;
            end

        end

        % Value changed function: PauseButton
        function PauseButtonValueChanged(app, event)
        if app.PauseButton.Value
            app.PauseButton.Text = 'Resume (Press Space)';
        else
            app.PauseButton.Text = 'Pause (Press Space)';
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.descriptionLabel = uilabel(app.UIFigure);
            app.descriptionLabel.Text = 'Set your wrist to neutral position and press ';
            app.TaskLabel = uilabel(app.UIFigure);
            app.TaskLabel.Text = 'Grip force';
            app.instructionLabel = uilabel(app.UIFigure);
            app.instructionLabel.Text = 'Match the required level of grip force by positioning the red dot in respective dots';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.blue = uiimage(app.UIFigure);
            app.blue.ImageSource = 'blue dot.png';
            app.trialCounter = uilabel(app.UIFigure);
            app.trialCounter.Text = '';
            app.counter = uilabel(app.UIFigure);
            app.counter.Text = '';
            app.darkRed = uiimage(app.UIFigure);
            app.darkRed.ImageSource = 'dot_black.png';
            app.green = uiimage(app.UIFigure);
            app.green.ImageSource = 'green dot.png';
            app.yellow = uiimage(app.UIFigure);
            app.yellow.ImageSource = 'Yellow_icon.png';
            app.red = uiimage(app.UIFigure);
            app.red.ImageSource = 'red_dot.png';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Visible = 'off';
            app.NextButton.Text = 'Next';
            app.logic = uibutton(app.UIFigure, 'push');
            app.logic.Enable = 'off';
            app.logic.Visible = 'off';
            app.PauseButton = uibutton(app.UIFigure, 'state');
            app.PauseButton.ValueChangedFcn = createCallbackFcn(app, @PauseButtonValueChanged, true);
            app.PauseButton.Visible = 'off';
            app.PauseButton.Text = 'Pause';
            app.enter = uiimage(app.UIFigure);
            app.enter.ImageSource = 'enter-key.png';
            app.rightArrow = uiimage(app.UIFigure);
            app.rightArrow.Visible = 'off';
            app.rightArrow.ImageSource = 'right arrow.png';
            app.upArrow = uiimage(app.UIFigure);
            app.upArrow.Visible = 'off';
            app.upArrow.ImageSource = 'up arrow.png';
            app.space = uiimage(app.UIFigure);
            app.space.Visible = 'off';
            app.space.ImageSource = 'spacebar-icon-25.jpg';
            app.logic_2Button = uibutton(app.UIFigure, 'state');
            app.logic_2Button.Enable = 'off';
            app.logic_2Button.Visible = 'off';
            app.logic_2Button.Text = 'logic_2';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_grip_receiver

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end