classdef App_grip_tracking < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Image                   matlab.ui.control.Image
        StartButton             matlab.ui.control.Button
        AngledegEditFieldLabel  matlab.ui.control.Label
        AngledegEditField       matlab.ui.control.NumericEditField
        descriptionLabel        matlab.ui.control.Label
        TaskLabel               matlab.ui.control.Label
        blue                    matlab.ui.control.Image
        red                     matlab.ui.control.Image
        counter                 matlab.ui.control.Label
        trialCounter            matlab.ui.control.Label
        PauseButton             matlab.ui.control.StateButton
        StartPositionButton     matlab.ui.control.Button
        speed                   matlab.ui.control.Label
        StartTrainingButton     matlab.ui.control.Button
        logic                   matlab.ui.control.Button
        logic_2                 matlab.ui.control.Button
        enter                   matlab.ui.control.Image
        rightArrow              matlab.ui.control.Image
        tKey                    matlab.ui.control.Image
        space                   matlab.ui.control.Image
        instructionLabel        matlab.ui.control.Label
        NextButton              matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
    end


    methods (Access = public)
        
function checkPauseStopStatus(app,flag)
        if app.PauseButton.Value
            app.counter.Visible=true;
            app.counter.Text='Paused'; 
            waitfor(app.PauseButton,'Value',false)
            app.counter.Visible=true;
            app.counter.Text='Ready..'; 
            pause(2);
            app.counter.Text='3';
            pause(1);
            app.counter.Text='2';
            pause(1);
            app.counter.Text='1';
            pause(1);
            app.counter.Visible=false;
            app.counter.Text=' '; 
        end
    end
end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
        %start with hiding the unnecessary text
        app.space.Visible=true;
        app.enter.Visible=false;
        app.trialCounter.Visible=true;
        app.TaskLabel.Visible=false;
        app.descriptionLabel.Visible=false;
        app.StartButton.Visible=false;
        app.PauseButton.Visible=true;
        app.NextButton.Visible=false;
        
        %variables
        gripTarget={};%cell aray for positional data
        gripPos={};%cell aray for positional data
        gripTargetPos={};
        hold = 10;
        rest = 5;
        numTrials=1;
        currentMaxP = 10000;
        currentMaxN = -10000;
        KW = 4; %8 //spring
        DW = 10; %1.1 //damper
        MW = 0; %0.001 //wall
        
        %initialize
        
        startPosition = 60*GetForce();
        posError = 0;
        posErrorPrev = 0;
        posErrorDiff = 0;
        posErrorDiffPrev = 0;
        posErrorDiffDifff = 0;
      
        %circle calcs and display
        r=850;
        x=640;
        y=0;
        th = 0:pi/800:pi;
        %xunit = r * cos(th) + x;
        %yunit = r * sin(th) + y;
        plot(app.UIAxes,1,1)
        xline(app.UIAxes,0, '--','color','blue')
        framePos=app.UIFigure.Position;
        app.UIAxes.Position = [493 100 1280 500];
        
        trials = 1;
        sinPos=0;
        
        while (trials <=numTrials)
        app.trialCounter.Text=['Trial # ' num2str(trials)];
        
        if ~isempty(app)%pause button functionality
        checkPauseStopStatus(app)
        end
        
        c = clock;
        clockStart = c(4)*3600+c(5)*60+c(6);
        clockStartPrev = clockStart;
        posClockPrev = clockStart;

        %this is while loop for 3,2,1,ready counter
        while (clockStart < clockStartPrev + rest)
        c = clock;
        clockStart = c(4)*3600+c(5)*60+c(6);
        if(clockStart > clockStartPrev + 4)
            app.counter.Text='1';
        elseif (clockStart > clockStartPrev + 3)
            app.counter.Text='2';
        elseif (clockStart > clockStartPrev + 2)
            app.counter.Text='3';   

        elseif (clockStart > clockStartPrev)
            app.counter.Text='Ready..';   
        end
        

        posError = -(60*GetForce() - (startPosition));
        angDeviation = posError/6400*90;
        angDeviation = floor(angDeviation) + floor( (angDeviation-floor(angDeviation))/0.25) * 0.25;
        %app.AngledegEditField.Value=angDeviation;
        
        %limit movement to +-50 degs
        if angDeviation>50
            angDeviation=50;
        elseif angDeviation<-50
            angDeviation=-50;
        end
        
        %blueY= yunit(4*(50.25))-400;
        app.blue.Position=[615,80,50,50];
        
        redY=-10*angDeviation+100;
        app.red.Position=[625,redY,30,30];
        pause(0.001)%for some reason this pause is needed for smootheness

        end
        
        app.counter.Text='';
        if ~isempty(app) %pause button functionality
        checkPauseStopStatus(app)
        end
        
        %initiate stuff before the trial loop
        c = clock;
        clockStart = c(4)*3600+c(5)*60+c(6);
        clockStartPrev = clockStart;
        posClockPrev = clockStart;
        gripData=[];
        gripTargetData=[];
        
        while (clockStart < clockStartPrev + hold)
            
            c = clock;
            clockStart = c(4)*3600+c(5)*60+c(6);
            

            posError = -(60*GetForce() - (startPosition));
            angDeviation = posError/6400*90;
            angDeviation = floor(angDeviation) + floor( (angDeviation-floor(angDeviation))/0.25) * 0.25;
            %app.AngledegEditField.Value=angDeviation;
            
            if angDeviation>50
                angDeviation=50;
            elseif angDeviation<-50
                angDeviation=-50;
            end
            
            %%eqs below govern behaviour of the blue dot
            sinTime=clockStart-clockStartPrev;
            sinPos=2*18.51*(sin(sinTime*pi/1.547)*sin(sinTime*pi/2.875));%18.51
            sinPos = floor(sinPos) + floor( (sinPos-floor(sinPos))/0.25) * 0.25;

            
            %blueY= yunit(4*(50.25+sinPos))-500;
            blueY= 6.8*(sinPos)+335;
            app.blue.Position=[615,blueY,50,50];
            gripTargetData=[gripTargetData sinPos];
            
            redY=-10*angDeviation+100;
            app.red.Position=[625,redY,30,30];
            gripData=[gripData angDeviation];
            
            pause(0.001)

        end
        %save values from this trial
        name=strcat('trial',num2str(trials));
        gripPos.(name) = gripData;
        gripTargetPos.(name) =gripTargetData;
        trials = trials + 1;
        end
        gripTarget.gripPos=gripPos;
        gripTarget.gripTargetPos=gripTargetPos;
        save('gripTarget.mat','gripTarget')
        app.trialCounter.Visible=false;
        app.StartButton.Visible=false;
        app.PauseButton.Visible=false;
        app.space.Visible=false;
        app.TaskLabel.Visible=false;
        app.descriptionLabel.Visible=false;
        app.rightArrow.Visible=true;
        app.NextButton.Visible=true;
        app.instructionLabel.Text='Congratulations, you have finished this part of the experiment! To continue, press ';
        app.logic.Enable=true;
        app.trialCounter.Text=' ';
            

        end

        % Value changed function: PauseButton
        function PauseButtonValueChanged(app, event)
        if app.PauseButton.Value
            app.PauseButton.Text = 'Resume (Press Space)';
        else
            app.PauseButton.Text = 'Pause (Press Space)';
        end
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            switch key
                case 'return'
                    if app.logic.Enable
                    StartPositionButtonPushed(app, event);
                    else
                        StartButtonPushed(app, event);
                    end
                case 'rightarrow'
                    NextButtonPushed(app, event);
                case 'space'
                    if app.PauseButton.Value == false
                        app.PauseButton.Value = true;
                    elseif app.PauseButton.Value == true
                        app.PauseButton.Value = false;
                    end
                case 't'
                    StartTrainingButtonPushed(app, event);
            end

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            grip = load('grip.mat');
            torque = load('torque.mat');
            target = load('target.mat');
            positioning = load('position.mat');
            Username = char(load('username.mat').Username);
            data={};
            gripname = strcat(Username,'_',char(datetime('now','Format','yyyy_MM_dd''T''HHmm')),'_grip');
            torquename = strcat(Username,'_',char(datetime('now','Format','yyyy_MM_dd''T''HHmm')),'_torque');
            targetname = strcat(Username,'_',char(datetime('now','Format','yyyy_MM_dd''T''HHmm')),'_target');
            positionname = strcat(Username,'_',char(datetime('now','Format','yyyy_MM_dd''T''HHmm')),'_position');
            
            data.(gripname) = grip;
            data.(torquename)=torque;
            data.(targetname)=target;
            data.(positionname)=positioning;
            
            dataname = strcat(Username,'_',char(datetime('now','Format','yyyy_MM_dd''T''HHmm')));
            
            
            save(dataname,'data')
            
            delete(app)
            
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            App_torque;
            app.delete;            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.Visible = 'off';
            app.Image.ImageSource = 'background line_e.png';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.AngledegEditFieldLabel = uilabel(app.UIFigure);
            app.AngledegEditFieldLabel.Visible = 'off';
            app.AngledegEditFieldLabel.Text = 'Angle[deg]';
            app.AngledegEditField = uieditfield(app.UIFigure, 'numeric');
            app.AngledegEditField.Visible = 'off';
            app.descriptionLabel = uilabel(app.UIFigure);
            app.descriptionLabel.Text = 'Set your arm to neutral position and press ';
            app.TaskLabel = uilabel(app.UIFigure);
            app.TaskLabel.Text = 'Follow the target';
            app.blue = uiimage(app.UIFigure);
            app.blue.ImageSource = 'blue dot.png';
            app.red = uiimage(app.UIFigure);
            app.red.ImageSource = 'red_dot.png';
            app.counter = uilabel(app.UIFigure);
            app.counter.Text = '';
            app.trialCounter = uilabel(app.UIFigure);
            app.trialCounter.Text = '';
            app.PauseButton = uibutton(app.UIFigure, 'state');
            app.PauseButton.ValueChangedFcn = createCallbackFcn(app, @PauseButtonValueChanged, true);
            app.PauseButton.Visible = 'off';
            app.PauseButton.Text = 'Pause';
            app.StartPositionButton = uibutton(app.UIFigure, 'push');
            app.StartPositionButton.Visible = 'off';
            app.StartPositionButton.Text = 'Start';
            app.speed = uilabel(app.UIFigure);
            app.speed.Text = '';
            app.StartTrainingButton = uibutton(app.UIFigure, 'push');
            app.StartTrainingButton.Visible = 'off';
            app.StartTrainingButton.Text = 'Training';
            app.logic = uibutton(app.UIFigure, 'push');
            app.logic.Enable = 'off';
            app.logic.Visible = 'off';
            app.logic_2 = uibutton(app.UIFigure, 'push');
            app.logic_2.Enable = 'off';
            app.logic_2.Visible = 'off';
            app.enter = uiimage(app.UIFigure);
            app.enter.ImageSource = 'enter-key.png';
            app.rightArrow = uiimage(app.UIFigure);
            app.rightArrow.Visible = 'off';
            app.rightArrow.ImageSource = 'right arrow.png';
            app.tKey = uiimage(app.UIFigure);
            app.tKey.Visible = 'off';
            app.tKey.ImageSource = 'T key.png';
            app.space = uiimage(app.UIFigure);
            app.space.Visible = 'off';
            app.space.ImageSource = 'spacebar-icon-25.jpg';
            app.instructionLabel = uilabel(app.UIFigure);
            app.instructionLabel.Text = '';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Visible = 'off';
            app.NextButton.Text = 'Next';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_grip_tracking

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end